classdef timeHistoryPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TimeHistoryPlotterUIFigure     matlab.ui.Figure
        DataFilesMenu                  matlab.ui.container.Menu
        LoadMenu_2                     matlab.ui.container.Menu
        LoadDemoDataMenu               matlab.ui.container.Menu
        TemplatesMenu                  matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        MiscMenu                       matlab.ui.container.Menu
        DateTimeFormatsummaryMenu      matlab.ui.container.Menu
        dateTimedocumentationMenu      matlab.ui.container.Menu
        TimeHistoryDataFilesPanel      matlab.ui.container.Panel
        PreviewDataButton              matlab.ui.control.Button
        LoadedFilesListBox             matlab.ui.control.ListBox
        DeleteButton                   matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        DataChannelswithinselectedfilePanel  matlab.ui.container.Panel
        SearchDataChannelsEditField    matlab.ui.control.EditField
        SearchEditFieldLabel           matlab.ui.control.Label
        DataChannelsListBox            matlab.ui.control.ListBox
        LinePropertiesPanel            matlab.ui.container.Panel
        NotFiniteCheckBox              matlab.ui.control.CheckBox
        DiffCheckBox                   matlab.ui.control.CheckBox
        AbsCheckBox                    matlab.ui.control.CheckBox
        normalize_ng11CheckBox         matlab.ui.control.CheckBox
        normalize01CheckBox            matlab.ui.control.CheckBox
        UserEquationEditField          matlab.ui.control.EditField
        UserEquationEditFieldLabel     matlab.ui.control.Label
        UnitsConversionDropDown        matlab.ui.control.DropDown
        UnitsConversionDropDownLabel   matlab.ui.control.Label
        BiastoZeroLineCheckBox         matlab.ui.control.CheckBox
        DifferentiateLineCheckBox      matlab.ui.control.CheckBox
        IntegrateLineCheckBox          matlab.ui.control.CheckBox
        yyaxisRightLineCheckBox        matlab.ui.control.CheckBox
        DontShowLineCheckBox           matlab.ui.control.CheckBox
        MinCheckBox                    matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        LineStyleTab                   matlab.ui.container.Tab
        MarkerFreqDropDown             matlab.ui.control.DropDown
        MarkerFreqDropDownLabel        matlab.ui.control.Label
        MarkerSizeDropDown             matlab.ui.control.DropDown
        MarkerSizeDropDownLabel        matlab.ui.control.Label
        StyleDropDown                  matlab.ui.control.DropDown
        StyleDropDownLabel             matlab.ui.control.Label
        MarkerDropDown                 matlab.ui.control.DropDown
        MarkerDropDownLabel            matlab.ui.control.Label
        LineWidthDropDown              matlab.ui.control.DropDown
        LineWidthDropDownLabel         matlab.ui.control.Label
        ColorDropDown                  matlab.ui.control.DropDown
        ColorDropDownLabel             matlab.ui.control.Label
        FiltersTab                     matlab.ui.container.Tab
        ApplyFilterLineCheckBox        matlab.ui.control.CheckBox
        ApplySmoothDataLineCheckBox    matlab.ui.control.CheckBox
        FilterbLineEditField           matlab.ui.control.EditField
        FilterbEditFieldLabel          matlab.ui.control.Label
        FilteraLineEditField           matlab.ui.control.EditField
        FilteraEditFieldLabel          matlab.ui.control.Label
        WindowLineEditField            matlab.ui.control.EditField
        SmoothDataWindowLabel          matlab.ui.control.Label
        MethodLineDropDown             matlab.ui.control.DropDown
        SmoothDataMethodLabel          matlab.ui.control.Label
        MaxCheckBox                    matlab.ui.control.CheckBox
        AxisPropertiesPanel            matlab.ui.container.Panel
        AxisNameasylabelCheckBox       matlab.ui.control.CheckBox
        HorizontalLinesEditField       matlab.ui.control.EditField
        HorizontalLinesEditFieldLabel  matlab.ui.control.Label
        LegendTitleEditField           matlab.ui.control.EditField
        LegendTitleEditFieldLabel      matlab.ui.control.Label
        logyCheckBox                   matlab.ui.control.CheckBox
        ymaxEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        yminEditFieldLabel             matlab.ui.control.Label
        DontShowAxisCheckBox           matlab.ui.control.CheckBox
        LegendLocationDropDown         matlab.ui.control.DropDown
        LegendLocationDropDownLabel    matlab.ui.control.Label
        TightCheckBox                  matlab.ui.control.CheckBox
        FigurePropertiesPanel          matlab.ui.container.Panel
        TimeMaxEditField               matlab.ui.control.NumericEditField
        TimeMaxEditFieldLabel          matlab.ui.control.Label
        TimeMinEditField               matlab.ui.control.NumericEditField
        TimeMinEditFieldLabel          matlab.ui.control.Label
        BiasTimetoZeroCheckBox         matlab.ui.control.CheckBox
        DateTimeFormatEditField        matlab.ui.control.EditField
        DateTimeFormatEditFieldLabel   matlab.ui.control.Label
        TextInterpreterDropDown        matlab.ui.control.DropDown
        TextInterpreterDropDownLabel   matlab.ui.control.Label
        TemplateNameinsgtitleCheckBox  matlab.ui.control.CheckBox
        sgtitleEditField               matlab.ui.control.EditField
        sgtitleEditFieldLabel          matlab.ui.control.Label
        PostxlabeltextEditField        matlab.ui.control.EditField
        PostxlabeltextEditFieldLabel   matlab.ui.control.Label
        VerticalLinesEditField         matlab.ui.control.EditField
        VerticalLinesEditFieldLabel    matlab.ui.control.Label
        logxCheckBox                   matlab.ui.control.CheckBox
        TileSpaceDropDown              matlab.ui.control.DropDown
        TileSpaceDropDownLabel         matlab.ui.control.Label
        BoldCheckBox                   matlab.ui.control.CheckBox
        FigureNumDropDown              matlab.ui.control.DropDown
        FigureDropDownLabel            matlab.ui.control.Label
        LinkAxesCheckBox               matlab.ui.control.CheckBox
        FileNameinsgtitleCheckBox      matlab.ui.control.CheckBox
        FigureSizeDropDown             matlab.ui.control.DropDown
        FigureSizeLabel                matlab.ui.control.Label
        MinorGridCheckBox              matlab.ui.control.CheckBox
        IndependentChannelDropDown     matlab.ui.control.DropDown
        IndependentChannelDropDownLabel  matlab.ui.control.Label
        MajorGridCheckBox              matlab.ui.control.CheckBox
        TemplatesPanel                 matlab.ui.container.Panel
        TmpTree                        matlab.ui.container.Tree
        CloseallButton                 matlab.ui.control.Button
        clcButton                      matlab.ui.control.Button
        LoadTemplateButton             matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        SaveTemplateButton             matlab.ui.control.Button
        ContextMenuTmpTree             matlab.ui.container.ContextMenu
        TemplatesMenu_2                matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        DeleteMenu                     matlab.ui.container.Menu
        DeleteAllMenu                  matlab.ui.container.Menu
        CopyMenu                       matlab.ui.container.Menu
        AxisMenu                       matlab.ui.container.Menu
        NewMenu_2                      matlab.ui.container.Menu
        DeleteMenu_2                   matlab.ui.container.Menu
        CopyMenu_2                     matlab.ui.container.Menu
        LineMenu                       matlab.ui.container.Menu
        NewMenu_3                      matlab.ui.container.Menu
        DeleteMenu_3                   matlab.ui.container.Menu
        CopyMenu_3                     matlab.ui.container.Menu
        ContextMenuDataChannels        matlab.ui.container.ContextMenu
        AddtocurrentAxisoverwriteMenu  matlab.ui.container.Menu
        AddtocurrentAxisnewlineMenu    matlab.ui.container.Menu
        AddtoNEWAxisMenu               matlab.ui.container.Menu
        AddtoNewTemplateonechannelperaxisMenu  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        tHData      % All time history data
        % templates   % All templates
        mapping
        cUnintsText
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Setup gui for use
            guiControl.onStartUp(app);

        end

        % Callback function: LoadButton, LoadMenu_2
        function LoadButtonPushed(app, event)
            dataLoader.topLevel(app);
        end

        % Value changed function: LoadedFilesListBox
        function LoadedFilesListBoxValueChanged(app, event)
            guiControl.listBoxLoadedFilesChanged(app);
        end

        % Button pushed function: clcButton
        function clcButtonPushed(app, event)
            clc
        end

        % Drop down opening function: IndependentChannelDropDown
        function IndependentChannelDropDownOpening(app, event)
            guiControl.independentChannel_DropDownOpen(app);
        end

        % Callback function
        function keyboardButtonPushed(app, event)
            keyboard
        end

        % Callback function
        function PrintButtonPushed(app, event)
            doPrint = true;
            guiControl.gatherTemplateIntoStruct(app, doPrint);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotting.topLevel(app);
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            guiControl.deleteFile(app);
        end

        % Value changing function: SearchDataChannelsEditField
        function SearchDataChannelsEditFieldValueChanging(app, event)
            guiControl.searchDataChannelNames(app, event.Value);
        end

        % Menu selected function: LoadDemoDataMenu
        function LoadDemoDataMenuSelected(app, event)
            dataLoader.loadDemoData(app);
        end

        % Close request function: TimeHistoryPlotterUIFigure
        function TimeHistoryPlotterUIFigureCloseRequest(app, event)
            
            selection = uiconfirm(app.TimeHistoryPlotterUIFigure, ...
                "Close ""Time History Plotter""", "Confirm Close", ...
                "Options",["Close","Cancel"], ...
                "Icon","warning");
            switch selection
                case {'Close'}
                    delete(app)
                case {'Cancel'}
            end
            
        end

        % Selection changed function: TmpTree
        function TmpTreeSelectionChanged(app, event)
            % selectedNodes = app.TmpTree.SelectedNodes;
            guiControl.treeSelectionChanged(app, event.SelectedNodes, event.PreviousSelectedNodes);
        end

        % Menu selected function: NewMenu
        function treeMenuTempNew(app, event)
            guiControl.makeNewTemplate(app);
        end

        % Menu selected function: DeleteMenu
        function treeMenuTempDelete(app, event)
            optDeleteAll = false;
            guiControl.deleteTemplate(app, optDeleteAll);
        end

        % Menu selected function: NewMenu_2
        function treeMenuAxisNew(app, event)
            guiControl.makeNewAxis(app);
        end

        % Menu selected function: DeleteMenu_2
        function treeMenuAxisDelete(app, event)
            guiControl.deleteAxis(app);
        end

        % Menu selected function: NewMenu_3
        function TreeMenuLineNew(app, event)
            guiControl.makeNewLine(app);
        end

        % Menu selected function: DeleteMenu_3
        function TreeMenuLineDelete(app, event)
            guiControl.deleteLine(app);
        end

        % Callback function
        function dataChanMenu_AddCurrent(app, event)
            guiControl.addToNewTmpOnePerAx(app);
        end

        % Menu selected function: AddtocurrentAxisoverwriteMenu
        function dataChanMenu_overWrite(app, event)
            optUseCurAxis   = true;
            optOverWrite    = true;
            guiControl.addCurrentToCurrentAxis(app, optUseCurAxis, optOverWrite);
        end

        % Menu selected function: AddtocurrentAxisnewlineMenu
        function dataChanMenu_newLine(app, event)
            optUseCurAxis   = true;
            optOverWrite    = false;
            guiControl.addCurrentToCurrentAxis(app, optUseCurAxis, optOverWrite);
        end

        % Menu selected function: AddtoNEWAxisMenu
        function dataChanMenu_newAxis(app, event)
            optUseCurAxis   = false;
            optOverWrite    = false;
            guiControl.addCurrentToCurrentAxis(app, optUseCurAxis, optOverWrite);
        end

        % Button pushed function: PreviewDataButton
        function PreviewLoadedTHData(app, event)
            dataLoader.previewData(app);
        end

        % Callback function: LoadMenu, LoadTemplateButton
        function MenuLoadTemplateSelected(app, event)
            templateLoader.loadFromFile(app);
        end

        % Callback function: SaveMenu, SaveTemplateButton
        function SaveTemplateSelected(app, event)
            templateLoader.saveToFile(app);
        end

        % Menu selected function: AddtoNewTemplateonechannelperaxisMenu
        function dataChanMenu_NewTmpOnePerAx(app, event)
            guiControl.addToNewTmpOnePerAx(app);
        end

        % Menu selected function: DeleteAllMenu
        function treeMenuTempDeleteAll(app, event)
            optDeleteAll = true;
            guiControl.deleteTemplate(app, optDeleteAll);
        end

        % Menu selected function: CopyMenu
        function dataChanMenu_CopyTmp(app, event)
            [hh] = guiControl.getNodeLocation(app, app.TmpTree.SelectedNodes);
            guiControl.copyNode(app, hh.tmp, app.TmpTree);
        end

        % Menu selected function: CopyMenu_2
        function dataChanMenu_CopyAxis(app, event)
            [hh] = guiControl.getNodeLocation(app, app.TmpTree.SelectedNodes);
            guiControl.copyNode(app, hh.ax, hh.tmp);
        end

        % Menu selected function: CopyMenu_3
        function dataChanMenu_CopyLine(app, event)
            [hh] = guiControl.getNodeLocation(app, app.TmpTree.SelectedNodes);
            guiControl.copyNode(app, hh.line, hh.ax);
        end

        % Value changed function: UnitsConversionDropDown
        function ddUnitsChangedCb(app, event)
            % value = app.UnitsConversionDropDown.Value;
            guiControl.unitsDdChanged(app);
        end

        % Menu selected function: DateTimeFormatsummaryMenu
        function menuDateTimeSummary(app, event)
            guiControl.outputDateTimeFormatInfo(app);
        end

        % Menu selected function: dateTimedocumentationMenu
        function menuDateTimeDoc(app, event)
            evalin('base', 'doc datetime')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TimeHistoryPlotterUIFigure = uifigure('Visible', 'off');
            app.TimeHistoryPlotterUIFigure.Name = 'Time History Plotter';
            app.TimeHistoryPlotterUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'AppIcon.jpg');
            app.TimeHistoryPlotterUIFigure.CloseRequestFcn = createCallbackFcn(app, @TimeHistoryPlotterUIFigureCloseRequest, true);
            app.DataFilesMenu = uimenu(app.TimeHistoryPlotterUIFigure);
            app.DataFilesMenu.Text = 'Data Files';
            app.LoadMenu_2 = uimenu(app.DataFilesMenu);
            app.LoadMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu_2.Text = 'Load';
            app.LoadDemoDataMenu = uimenu(app.DataFilesMenu);
            app.LoadDemoDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDemoDataMenuSelected, true);
            app.LoadDemoDataMenu.Text = 'Load Demo Data';
            app.TemplatesMenu = uimenu(app.TimeHistoryPlotterUIFigure);
            app.TemplatesMenu.Text = 'Templates';
            app.SaveMenu = uimenu(app.TemplatesMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveTemplateSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.TemplatesMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @MenuLoadTemplateSelected, true);
            app.LoadMenu.Text = 'Load';
            app.MiscMenu = uimenu(app.TimeHistoryPlotterUIFigure);
            app.MiscMenu.Text = 'Misc';
            app.DateTimeFormatsummaryMenu = uimenu(app.MiscMenu);
            app.DateTimeFormatsummaryMenu.MenuSelectedFcn = createCallbackFcn(app, @menuDateTimeSummary, true);
            app.DateTimeFormatsummaryMenu.Text = 'Date Time Format summary';
            app.dateTimedocumentationMenu = uimenu(app.MiscMenu);
            app.dateTimedocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @menuDateTimeDoc, true);
            app.dateTimedocumentationMenu.Text = 'dateTime documentation';
            app.TemplatesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.TemplatesPanel.Title = 'Templates';
            app.SaveTemplateButton = uibutton(app.TemplatesPanel, 'push');
            app.SaveTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTemplateSelected, true);
            app.SaveTemplateButton.Icon = fullfile(pathToMLAPP, 'icons', 'save-icon--1.jpg');
            app.SaveTemplateButton.Tooltip = {'Plot currently selected data file with currently selected template'};
            app.SaveTemplateButton.Text = 'Save Template';
            app.PlotButton = uibutton(app.TemplatesPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'icons', 'multiple-line-graph-flat-icon-free-vector.jpg');
            app.PlotButton.Tooltip = {'Plot currently selected data file with currently selected template'};
            app.PlotButton.Text = 'Plot';
            app.LoadTemplateButton = uibutton(app.TemplatesPanel, 'push');
            app.LoadTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @MenuLoadTemplateSelected, true);
            app.LoadTemplateButton.Icon = fullfile(pathToMLAPP, 'icons', 'loadIcon.jpg');
            app.LoadTemplateButton.Tooltip = {'Plot currently selected data file with currently selected template'};
            app.LoadTemplateButton.Text = 'Load Template';
            app.clcButton = uibutton(app.TemplatesPanel, 'push');
            app.clcButton.ButtonPushedFcn = createCallbackFcn(app, @clcButtonPushed, true);
            app.clcButton.Text = 'clc';
            app.CloseallButton = uibutton(app.TemplatesPanel, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.TmpTree = uitree(app.TemplatesPanel);
            app.TmpTree.SelectionChangedFcn = createCallbackFcn(app, @TmpTreeSelectionChanged, true);
            app.TmpTree.Editable = 'on';
            app.TmpTree.Tooltip = {'Template specification area.  '; 'Double click on text to change names.'};
            app.FigurePropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.FigurePropertiesPanel.Title = 'Figure Properties';
            app.MajorGridCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.MajorGridCheckBox.Tooltip = {'Add Major Grid to plot'};
            app.MajorGridCheckBox.Text = 'Major Grid';
            app.IndependentChannelDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.IndependentChannelDropDownLabel.Text = 'Independent Channel';
            app.IndependentChannelDropDown = uidropdown(app.FigurePropertiesPanel);
            app.IndependentChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @IndependentChannelDropDownOpening, true);
            app.IndependentChannelDropDown.Tooltip = {'Independetn data channel, commonly time.  '};
            app.MinorGridCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.MinorGridCheckBox.Tooltip = {'Add Minor Grid to plot'};
            app.MinorGridCheckBox.Text = 'Minor Grid';
            app.FigureSizeLabel = uilabel(app.FigurePropertiesPanel);
            app.FigureSizeLabel.Text = 'Figure Size';
            app.FigureSizeDropDown = uidropdown(app.FigurePropertiesPanel);
            app.FigureSizeDropDown.Items = {'Dont change', '750, 500 - small landscape', '1000, 640 - medium landscape', '1400, 850 - landscape', '560, 600 - small portrait', '830, 800 - medium portrait', '1050, 1000 - portrait', 'Maximize'};
            app.FigureSizeDropDown.Tooltip = {'Plot figure size'};
            app.FigureSizeDropDown.Value = 'Dont change';
            app.FileNameinsgtitleCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.FileNameinsgtitleCheckBox.Tooltip = {'Include time history data file name in figure sgtitle'};
            app.FileNameinsgtitleCheckBox.Text = 'File Name in sgtitle';
            app.LinkAxesCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.LinkAxesCheckBox.Tooltip = {'Link all axes within figure'};
            app.LinkAxesCheckBox.Text = 'Link Axes';
            app.FigureDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.FigureDropDownLabel.Text = 'Figure';
            app.FigureNumDropDown = uidropdown(app.FigurePropertiesPanel);
            app.FigureNumDropDown.Items = {'gcf', 'figure', 'figure(1)', 'figure(2)', 'figure(3)', 'figure(4)', 'figure(5)', 'figure(6)', 'figure(7)', 'figure(8)', 'figure(9)'};
            app.FigureNumDropDown.Tooltip = {'Plot figure number'};
            app.FigureNumDropDown.Value = 'gcf';
            app.BoldCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.BoldCheckBox.Tooltip = {'Make text BOLD, fonts and lines larger.  Helps readability whne pasting images into emails (etc).'};
            app.BoldCheckBox.Text = 'Bold';
            app.TileSpaceDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.TileSpaceDropDownLabel.Text = 'Tile Space';
            app.TileSpaceDropDown = uidropdown(app.FigurePropertiesPanel);
            app.TileSpaceDropDown.Items = {'loose', 'compact', 'tight', 'none'};
            app.TileSpaceDropDown.Tooltip = {'Specifies "tiledlayout"'};
            app.TileSpaceDropDown.Value = 'loose';
            app.logxCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.logxCheckBox.Tooltip = {'Make independent axis logarithmic.'};
            app.logxCheckBox.Text = 'logx';
            app.VerticalLinesEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.VerticalLinesEditFieldLabel.Text = 'Vertical Lines';
            app.VerticalLinesEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.VerticalLinesEditField.Tooltip = {'Add vertical lines to all axes, space or comma delineated.'};
            app.PostxlabeltextEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.PostxlabeltextEditFieldLabel.Text = 'Post xlabel text';
            app.PostxlabeltextEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.PostxlabeltextEditField.Tooltip = {'Text added to autogenerated xlabel'};
            app.sgtitleEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.sgtitleEditFieldLabel.Text = 'sgtitle';
            app.sgtitleEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.sgtitleEditField.Tooltip = {'Text added to sgtitle'};
            app.TemplateNameinsgtitleCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.TemplateNameinsgtitleCheckBox.Tooltip = {'Include template name in figure sgtitle'};
            app.TemplateNameinsgtitleCheckBox.Text = 'Template Name in sgtitle';
            app.TextInterpreterDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.TextInterpreterDropDownLabel.Text = 'Text Interpreter';
            app.TextInterpreterDropDown = uidropdown(app.FigurePropertiesPanel);
            app.TextInterpreterDropDown.Items = {'none', 'tex'};
            app.TextInterpreterDropDown.Editable = 'on';
            app.TextInterpreterDropDown.Tooltip = {'Text interpreter for labels and titles.'};
            app.TextInterpreterDropDown.Value = 'none';
            app.DateTimeFormatEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.DateTimeFormatEditFieldLabel.Text = 'Date Time Format';
            app.DateTimeFormatEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.DateTimeFormatEditField.Tooltip = {'"datetime" format used when Independent Channel is in a calendar format.'; 'See Menu "Misc" for format information.'};
            app.BiasTimetoZeroCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.BiasTimetoZeroCheckBox.Tooltip = {'Make independent axis logarithmic.'};
            app.BiasTimetoZeroCheckBox.Text = 'Bias Time to Zero';
            app.TimeMinEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.TimeMinEditFieldLabel.Text = 'Time Min';
            app.TimeMinEditField = uieditfield(app.FigurePropertiesPanel, 'numeric');
            app.TimeMinEditField.Tooltip = {'Left (min) bound for Independent Channel.  -inf applies min from data channel.'};
            app.TimeMinEditField.Value = -Inf;
            app.TimeMaxEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.TimeMaxEditFieldLabel.Text = 'Time Max';
            app.TimeMaxEditField = uieditfield(app.FigurePropertiesPanel, 'numeric');
            app.TimeMaxEditField.Tooltip = {'Right (max) bound for Independent Channel.  inf applies max from data channel.'};
            app.TimeMaxEditField.Value = Inf;
            app.AxisPropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.AxisPropertiesPanel.Enable = 'off';
            app.AxisPropertiesPanel.Title = 'Axis Properties';
            app.TightCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.TightCheckBox.Tooltip = {'Make current axis tight'};
            app.TightCheckBox.Text = 'Tight';
            app.LegendLocationDropDownLabel = uilabel(app.AxisPropertiesPanel);
            app.LegendLocationDropDownLabel.Text = 'Legend Location';
            app.LegendLocationDropDown = uidropdown(app.AxisPropertiesPanel);
            app.LegendLocationDropDown.Items = {'best', 'northeast', 'northwest', 'north', 'south', 'east', 'west', 'southeast', 'southwest', 'northoutside', 'southoutside', 'eastoutside', 'westoutside', 'northeastoutside', 'northwestoutside', 'southeastoutside', 'southwestoutside', 'bestoutside', 'none'};
            app.LegendLocationDropDown.Tooltip = {'Legend location'};
            app.LegendLocationDropDown.Value = 'best';
            app.DontShowAxisCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.DontShowAxisCheckBox.Tooltip = {'Exclude axis from fiture'};
            app.DontShowAxisCheckBox.Text = 'Dont Show';
            app.yminEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.AxisPropertiesPanel, 'numeric');
            app.yminEditField.Tooltip = {'y minimum used with ylim in axis'};
            app.ymaxEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.AxisPropertiesPanel, 'numeric');
            app.ymaxEditField.Tooltip = {'y maximum used with ylim in axis'};
            app.logyCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.logyCheckBox.Tooltip = {'Make dependent axis logarithmic.'};
            app.logyCheckBox.Text = 'logy';
            app.LegendTitleEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.LegendTitleEditFieldLabel.Text = 'Legend Title';
            app.LegendTitleEditField = uieditfield(app.AxisPropertiesPanel, 'text');
            app.LegendTitleEditField.Tooltip = {'Title added to legend'};
            app.HorizontalLinesEditFieldLabel = uilabel(app.AxisPropertiesPanel);
            app.HorizontalLinesEditFieldLabel.Text = 'Horizontal Lines';
            app.HorizontalLinesEditField = uieditfield(app.AxisPropertiesPanel, 'text');
            app.HorizontalLinesEditField.Tooltip = {'Horizontal lines to be added to axis, space or comma delineated.'};
            app.AxisNameasylabelCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.AxisNameasylabelCheckBox.Tooltip = {'Add axis name as ylabel'};
            app.AxisNameasylabelCheckBox.Text = 'Axis Name as ylabel';
            app.LinePropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.LinePropertiesPanel.Enable = 'off';
            app.LinePropertiesPanel.Title = 'Line Properties';
            app.MaxCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.MaxCheckBox.Tooltip = {'Mark max data point on curve.'};
            app.MaxCheckBox.Text = 'Max';
            app.TabGroup = uitabgroup(app.LinePropertiesPanel);
            app.LineStyleTab = uitab(app.TabGroup);
            app.LineStyleTab.Tooltip = {'Specify how curve line is drawn.'};
            app.LineStyleTab.Title = 'Line Style';
            app.ColorDropDownLabel = uilabel(app.LineStyleTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LineStyleTab);
            app.ColorDropDown.Items = {'automatic', 'black', 'blue', 'orange', 'yellow', 'purple', 'green', 'light blue', 'red', 'magenta'};
            app.ColorDropDown.Tooltip = {'Curve color specification.'};
            app.ColorDropDown.Value = 'automatic';
            app.LineWidthDropDownLabel = uilabel(app.LineStyleTab);
            app.LineWidthDropDownLabel.Text = 'Line Width';
            app.LineWidthDropDown = uidropdown(app.LineStyleTab);
            app.LineWidthDropDown.Items = {'0.1', '0.25', '0.5', '0.75', '1.0', '1.5', '2.0', '2.5', '3.0', '4.0', '5.0', '6.0'};
            app.LineWidthDropDown.Tooltip = {'Curves line width.'};
            app.LineWidthDropDown.Value = '0.1';
            app.MarkerDropDownLabel = uilabel(app.LineStyleTab);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.LineStyleTab);
            app.MarkerDropDown.Items = {'none', 'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram'};
            app.MarkerDropDown.Tooltip = {'Marker appled to line. '};
            app.MarkerDropDown.Value = 'none';
            app.StyleDropDownLabel = uilabel(app.LineStyleTab);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.LineStyleTab);
            app.StyleDropDown.Items = {'-', ':', '-.', '--', 'none'};
            app.StyleDropDown.Tooltip = {'Curve line style'};
            app.StyleDropDown.Value = '-';
            app.MarkerSizeDropDownLabel = uilabel(app.LineStyleTab);
            app.MarkerSizeDropDownLabel.Text = 'Marker Size';
            app.MarkerSizeDropDown = uidropdown(app.LineStyleTab);
            app.MarkerSizeDropDown.Items = {'2', '4', '5', '6', '7', '8', '10', '12', '14', '16', '18', '20', '25', '30', '35', '40'};
            app.MarkerSizeDropDown.Tooltip = {'Marker size.'};
            app.MarkerSizeDropDown.Value = '6';
            app.MarkerFreqDropDownLabel = uilabel(app.LineStyleTab);
            app.MarkerFreqDropDownLabel.Text = 'Marker Freq';
            app.MarkerFreqDropDown = uidropdown(app.LineStyleTab);
            app.MarkerFreqDropDown.Items = {'1', '2', '5', '10', '20', '25', '40', '50', '60', '75', '100', '150', '200', '250', '300', '400', '500', '1000'};
            app.MarkerFreqDropDown.Tooltip = {'Marker frequency'};
            app.MarkerFreqDropDown.Value = '1';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Tooltip = {'Filters optionally applied to curves.  '};
            app.FiltersTab.Title = 'Filters';
            app.SmoothDataMethodLabel = uilabel(app.FiltersTab);
            app.SmoothDataMethodLabel.Text = 'Method';
            app.MethodLineDropDown = uidropdown(app.FiltersTab);
            app.MethodLineDropDown.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.MethodLineDropDown.Tooltip = {'Mehod used with "SmoothData"'};
            app.MethodLineDropDown.Value = 'movmean';
            app.SmoothDataWindowLabel = uilabel(app.FiltersTab);
            app.SmoothDataWindowLabel.Text = 'Window';
            app.WindowLineEditField = uieditfield(app.FiltersTab, 'text');
            app.WindowLineEditField.Tooltip = {'Argument passed into "SmoothData"'};
            app.WindowLineEditField.Value = '4';
            app.FilteraEditFieldLabel = uilabel(app.FiltersTab);
            app.FilteraEditFieldLabel.Text = '"Filter" a';
            app.FilteraLineEditField = uieditfield(app.FiltersTab, 'text');
            app.FilteraLineEditField.Tooltip = {'filter argument "A"'};
            app.FilteraLineEditField.Value = '1';
            app.FilterbEditFieldLabel = uilabel(app.FiltersTab);
            app.FilterbEditFieldLabel.Text = '"Filter" b';
            app.FilterbLineEditField = uieditfield(app.FiltersTab, 'text');
            app.FilterbLineEditField.Tooltip = {'filter argument "B"'};
            app.FilterbLineEditField.Value = '0.2 0.2 0.2 0.2 0.2';
            app.ApplySmoothDataLineCheckBox = uicheckbox(app.FiltersTab);
            app.ApplySmoothDataLineCheckBox.Tooltip = {'Apply function "SmoothData" to curve.  '};
            app.ApplySmoothDataLineCheckBox.Text = 'Apply "SmoothData"';
            app.ApplyFilterLineCheckBox = uicheckbox(app.FiltersTab);
            app.ApplyFilterLineCheckBox.Tooltip = {'Apply matlab function "filter" to curve. '};
            app.ApplyFilterLineCheckBox.Text = 'Apply "Filter"';
            app.MinCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.MinCheckBox.Tooltip = {'Mark max data point on curve.'};
            app.MinCheckBox.Text = 'Min';
            app.DontShowLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.DontShowLineCheckBox.Tooltip = {'Exclude curve from plotted axis '};
            app.DontShowLineCheckBox.Text = 'Dont Show';
            app.yyaxisRightLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.yyaxisRightLineCheckBox.Tooltip = {'Add curve to right axis, uses yyaxis'};
            app.yyaxisRightLineCheckBox.Text = 'yyaxis right';
            app.IntegrateLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.IntegrateLineCheckBox.Tooltip = {'Find and plots the integral of the curve.  uses "cumtrapz"'};
            app.IntegrateLineCheckBox.Text = 'Integrate';
            app.DifferentiateLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.DifferentiateLineCheckBox.Tooltip = {'Find and plots the derivative of the curve.  uses "gradient"'};
            app.DifferentiateLineCheckBox.Text = 'Differentiate';
            app.BiastoZeroLineCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.BiastoZeroLineCheckBox.Tooltip = {'Bias curve at t(0) to zero. '};
            app.BiastoZeroLineCheckBox.Text = 'Bias to Zero';
            app.UnitsConversionDropDownLabel = uilabel(app.LinePropertiesPanel);
            app.UnitsConversionDropDownLabel.Text = 'Units Conversion';
            app.UnitsConversionDropDown = uidropdown(app.LinePropertiesPanel);
            app.UnitsConversionDropDown.Editable = 'on';
            app.UnitsConversionDropDown.ValueChangedFcn = createCallbackFcn(app, @ddUnitsChangedCb, true);
            app.UnitsConversionDropDown.Tooltip = {'Unit conversion'};
            app.UserEquationEditFieldLabel = uilabel(app.LinePropertiesPanel);
            app.UserEquationEditFieldLabel.Text = 'User Equation';
            app.UserEquationEditField = uieditfield(app.LinePropertiesPanel, 'text');
            app.UserEquationEditField.Tooltip = {'User specified equation, input used with eval() function.  Dependent data variable is xx, independent is tt.  '; ''; 'Example: tt.*0.3.*xx + 1.0'};
            app.normalize01CheckBox = uicheckbox(app.LinePropertiesPanel);
            app.normalize01CheckBox.Tooltip = {'depData = interp1([min(depData), max(depData)], [0 1], depData);'};
            app.normalize01CheckBox.Text = 'Normalize [0, 1]';
            app.normalize_ng11CheckBox = uicheckbox(app.LinePropertiesPanel);
            app.normalize_ng11CheckBox.Tooltip = {'depData = interp1([min(depData), 0, max(depData)], [-1, 0, 1], depData);'};
            app.normalize_ng11CheckBox.Text = 'Normalize [-1, 1]';
            app.AbsCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.AbsCheckBox.Tooltip = {'Abs'};
            app.AbsCheckBox.Text = 'Abs';
            app.DiffCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.DiffCheckBox.Tooltip = {'diff'};
            app.DiffCheckBox.Text = 'Diff';
            app.NotFiniteCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.NotFiniteCheckBox.Tooltip = {'Adds vertical line where data are not finite.  ~isfinite() applied before all mathematical operations.'};
            app.NotFiniteCheckBox.Text = 'Not Finite';
            app.DataChannelswithinselectedfilePanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.DataChannelswithinselectedfilePanel.Title = 'Data Channels - within selected file';
            app.DataChannelsListBox = uilistbox(app.DataChannelswithinselectedfilePanel);
            app.DataChannelsListBox.Multiselect = 'on';
            app.DataChannelsListBox.Value = {'Item 1'};
            app.SearchEditFieldLabel = uilabel(app.DataChannelswithinselectedfilePanel);
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchDataChannelsEditField = uieditfield(app.DataChannelswithinselectedfilePanel, 'text');
            app.SearchDataChannelsEditField.ValueChangingFcn = createCallbackFcn(app, @SearchDataChannelsEditFieldValueChanging, true);
            app.SearchDataChannelsEditField.Tooltip = {'Search for and filter Data Channels'};
            app.TimeHistoryDataFilesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.TimeHistoryDataFilesPanel.Title = 'Time History Data Files';
            app.LoadButton = uibutton(app.TimeHistoryDataFilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load a time history file.'};
            app.LoadButton.Text = 'Load';
            app.DeleteButton = uibutton(app.TimeHistoryDataFilesPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Tooltip = {'Delete currently selected time history file'};
            app.DeleteButton.Text = 'Delete';
            app.LoadedFilesListBox = uilistbox(app.TimeHistoryDataFilesPanel);
            app.LoadedFilesListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedFilesListBoxValueChanged, true);
            app.PreviewDataButton = uibutton(app.TimeHistoryDataFilesPanel, 'push');
            app.PreviewDataButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewLoadedTHData, true);
            app.PreviewDataButton.Tooltip = {'Write top of time history file to Command Window'};
            app.PreviewDataButton.Text = 'Preview Data';
            app.ContextMenuTmpTree = uicontextmenu(app.TimeHistoryPlotterUIFigure);
            app.TemplatesMenu_2 = uimenu(app.ContextMenuTmpTree);
            app.TemplatesMenu_2.Text = 'Templates';
            app.NewMenu = uimenu(app.TemplatesMenu_2);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @treeMenuTempNew, true);
            app.NewMenu.Text = 'New';
            app.DeleteMenu = uimenu(app.TemplatesMenu_2);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @treeMenuTempDelete, true);
            app.DeleteMenu.Text = 'Delete';
            app.DeleteAllMenu = uimenu(app.TemplatesMenu_2);
            app.DeleteAllMenu.MenuSelectedFcn = createCallbackFcn(app, @treeMenuTempDeleteAll, true);
            app.DeleteAllMenu.Text = 'Delete All';
            app.CopyMenu = uimenu(app.TemplatesMenu_2);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_CopyTmp, true);
            app.CopyMenu.Text = 'Copy';
            app.AxisMenu = uimenu(app.ContextMenuTmpTree);
            app.AxisMenu.Text = 'Axis';
            app.NewMenu_2 = uimenu(app.AxisMenu);
            app.NewMenu_2.MenuSelectedFcn = createCallbackFcn(app, @treeMenuAxisNew, true);
            app.NewMenu_2.Text = 'New';
            app.DeleteMenu_2 = uimenu(app.AxisMenu);
            app.DeleteMenu_2.MenuSelectedFcn = createCallbackFcn(app, @treeMenuAxisDelete, true);
            app.DeleteMenu_2.Text = 'Delete';
            app.CopyMenu_2 = uimenu(app.AxisMenu);
            app.CopyMenu_2.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_CopyAxis, true);
            app.CopyMenu_2.Text = 'Copy';
            app.LineMenu = uimenu(app.ContextMenuTmpTree);
            app.LineMenu.Text = 'Line';
            app.NewMenu_3 = uimenu(app.LineMenu);
            app.NewMenu_3.MenuSelectedFcn = createCallbackFcn(app, @TreeMenuLineNew, true);
            app.NewMenu_3.Text = 'New';
            app.DeleteMenu_3 = uimenu(app.LineMenu);
            app.DeleteMenu_3.MenuSelectedFcn = createCallbackFcn(app, @TreeMenuLineDelete, true);
            app.DeleteMenu_3.Text = 'Delete';
            app.CopyMenu_3 = uimenu(app.LineMenu);
            app.CopyMenu_3.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_CopyLine, true);
            app.CopyMenu_3.Text = 'Copy';
            % Assign app.ContextMenuTmpTree
            app.TmpTree.ContextMenu = app.ContextMenuTmpTree;
            app.ContextMenuDataChannels = uicontextmenu(app.TimeHistoryPlotterUIFigure);
            app.AddtocurrentAxisoverwriteMenu = uimenu(app.ContextMenuDataChannels);
            app.AddtocurrentAxisoverwriteMenu.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_overWrite, true);
            app.AddtocurrentAxisoverwriteMenu.Text = 'Add to current Axis (overwrite)';
            app.AddtocurrentAxisnewlineMenu = uimenu(app.ContextMenuDataChannels);
            app.AddtocurrentAxisnewlineMenu.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_newLine, true);
            app.AddtocurrentAxisnewlineMenu.Text = 'Add to current Axis (new line)';
            app.AddtoNEWAxisMenu = uimenu(app.ContextMenuDataChannels);
            app.AddtoNEWAxisMenu.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_newAxis, true);
            app.AddtoNEWAxisMenu.Text = 'Add to NEW Axis';
            app.AddtoNewTemplateonechannelperaxisMenu = uimenu(app.ContextMenuDataChannels);
            app.AddtoNewTemplateonechannelperaxisMenu.MenuSelectedFcn = createCallbackFcn(app, @dataChanMenu_NewTmpOnePerAx, true);
            app.AddtoNewTemplateonechannelperaxisMenu.Text = 'Add to New Template, one channel per axis';
            % Assign app.ContextMenuDataChannels
            app.DataChannelsListBox.ContextMenu = app.ContextMenuDataChannels;
            % Show the figure after all components are created
            app.TimeHistoryPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timeHistoryPlotter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TimeHistoryPlotterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TimeHistoryPlotterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TimeHistoryPlotterUIFigure)
        end
    end
end